classdef plant_classification_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlantIdentificationUIFigure  matlab.ui.Figure
        Image                        matlab.ui.control.Image
        ImageLabel                   matlab.ui.control.Label
        LoadImageButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
         global image1;
         [filename, pathname]=uigetfile({'*.jpg'},"Open File");
         fullpathname=strcat(pathname,filename);
         image1=imread(fullpathname);
         app.Image.ImageSource=image1;
         load Flowernet.mat
         inputSize = net.Layers(1).InputSize(1:2);
         image1 = imresize(image1,inputSize);
         [predicted,scores]=classify(Flowernet,image1);
         tableofscores=[table(predicted) array2table(scores,"VariableNames",...
             categories(valset.response))];
         ax = app.UIAxes;
         x = scores;
         y = categorical({'daisy','dandelion','rose','sunflower','tulip'});
         barh(ax,y,x)


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlantIdentificationUIFigure = uifigure('Visible', 'off');
            app.PlantIdentificationUIFigure.Name = 'Plant Identification';
            app.Image = uiimage(app.PlantIdentificationUIFigure);
            app.ImageLabel = uilabel(app.PlantIdentificationUIFigure);
            app.ImageLabel.Text = 'Image';
            app.LoadImageButton = uibutton(app.PlantIdentificationUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = {'Load Image'; ''};
            app.UIAxes = uiaxes(app.PlantIdentificationUIFigure);
            title(app.UIAxes, 'Chart')
            xlabel(app.UIAxes, '%Percentage')
            ylabel(app.UIAxes, 'Predictions')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.PlantIdentificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plant_classification_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlantIdentificationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlantIdentificationUIFigure)
        end
    end
end